classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        interfazprincipal        matlab.ui.Figure
        LogoModoChulo            matlab.ui.control.Image
        RenderImagen             matlab.ui.control.Image
        PuertonoconectadoLabel   matlab.ui.control.Label
        PuertoconectadoLuz       matlab.ui.control.Lamp
        ConexionArduinoBoton     matlab.ui.control.Button
        ConectarBoton            matlab.ui.control.Button
        BotonHoming              matlab.ui.control.Button
        MotorAEditFieldLabel     matlab.ui.control.Label
        MotorAValue              matlab.ui.control.NumericEditField
        MotorBEditFieldLabel     matlab.ui.control.Label
        MotorBValue              matlab.ui.control.NumericEditField
        MotorCEditFieldLabel     matlab.ui.control.Label
        MotorCValue              matlab.ui.control.NumericEditField
        PosicionGrupo            matlab.ui.container.ButtonGroup
        Cartesianas              matlab.ui.control.ToggleButton
        Articulares              matlab.ui.control.ToggleButton
        PosicinXEditFieldLabel   matlab.ui.control.Label
        Motor1_X                 matlab.ui.control.NumericEditField
        PosicinYEditFieldLabel   matlab.ui.control.Label
        Motor2_Y                 matlab.ui.control.NumericEditField
        PosicinZEditFieldLabel   matlab.ui.control.Label
        Motor3_Z                 matlab.ui.control.NumericEditField
        BotonEnviar              matlab.ui.control.Button
        StepperEditFieldLabel_2  matlab.ui.control.Label
        Stepper                  matlab.ui.control.NumericEditField
        TrayectoriaEleccion      matlab.ui.container.ButtonGroup
        InterpolarBoton          matlab.ui.control.ToggleButton
        RectaBoton               matlab.ui.control.ToggleButton
        GarraSwitch              matlab.ui.control.RockerSwitch
        CirCulo                  matlab.ui.control.Button
        EjeZEditFieldLabel       matlab.ui.control.Label
        EjeZCirculo              matlab.ui.control.NumericEditField
        RadioEditFieldLabel      matlab.ui.control.Label
        RadioCirculo             matlab.ui.control.NumericEditField
        StepperEditFieldLabel    matlab.ui.control.Label
        StepperValue             matlab.ui.control.NumericEditField
        BotonSetFree             matlab.ui.control.Button
        Depurar                  matlab.ui.control.Button
        Logo                     matlab.ui.control.Image
    end

    
    properties (Access = public)  %Aqui van datos que se actualizan en otras apps
        mi_arduino; % El puerto serial para acceder desde más apps
        LosBaudios = 9600; %El dato de los Baudios que actualizaremos en otra app
        ElPuerto = "COM8"; %El dato del puerto del arduino que actualizaremos en otra app
        configset = false; 
        caso = '2';
        modoChulo = false;
    end    
    
    methods (Access = public)
    
        function updateplot(app, baudio, com) %funcion para actualizar valores desde otra app
            
            app.LosBaudios = baudio;   %almacenamos las variables como propiedades            
            app.ElPuerto = com;
            app.configset = true;
            
            app.ConexionArduinoBoton.Enable = true;     %desbloqueamos este boton al cerrar la otra app
        end
        
    end
    
    
    methods (Access = private)
        
        function pulsos = ActualizarPulsos(app)
            
            app.MotorAValue.Value = str2double(readline(app.mi_arduino));
            pulsos(1) = app.MotorAValue.Value;
            app.MotorBValue.Value = str2double(readline(app.mi_arduino));
            pulsos(2) = app.MotorBValue.Value;
            app.MotorCValue.Value = str2double(readline(app.mi_arduino));
            pulsos(3) = app.MotorCValue.Value;
            app.StepperValue.Value = str2double(readline(app.mi_arduino));
            pulsos(4) = app.StepperValue.Value;
            pulsos
            
        end
        
        function BloquearBotones(app, bool)
            
            app.BotonSetFree.Enable = bool;
            app.BotonHoming.Enable = bool;
            app.BotonEnviar.Enable = bool;
            app.CirCulo.Enable = bool;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConexionArduinoBoton
        function AbrirVentanaConfigSerialPort(app, event)
           app.ConexionArduinoBoton.Enable = false;
           VentanaArduino(app);
        end

        % Button pushed function: ConectarBoton
        function CrearSerialPort(app, event)
%Crearemos el serialport con los datos que hemos configurado.
%El try catch hace que si falla la creacion del serialport
%nos sale una pestaña de que no funciona para intentarlo de nuevo
        
        if app.configset == false
            app.ElPuerto = serialportlist("available");
        end

            try
                app.mi_arduino=serialport(app.ElPuerto,app.LosBaudios);
                configureTerminator(app.mi_arduino,"CR/LF", "LF");
                %configureTerminator(app.mi_arduino,"CR/LF");
                flush(app.mi_arduino);                
                app.PuertonoconectadoLabel.Text = 'Puerto Conectado';
                app.ConexionArduinoBoton.Enable = false;
                app.ConectarBoton.Enable = false;
                pause(2);
                write(app.mi_arduino, '3', "char"); %Envia la op 3 para tener los pulsos
                ActualizarPulsos(app);
                app.PuertoconectadoLuz.Color = 'green';
                BloquearBotones(app,true);
                
                
            catch
                VentanaAdvertir;
            end
            
        end

        % Selection changed function: PosicionGrupo
        function CambioSistCord(app, event)
            selectedButton = app.PosicionGrupo.SelectedObject;
            
            if selectedButton == app.Cartesianas
                app.PosicinXEditFieldLabel.Text = "Posición X:";
                app.PosicinYEditFieldLabel.Text = "Posición Y:";
                app.PosicinZEditFieldLabel.Text = "Posición Z:";
                app.Stepper.Visible = false;
                app.StepperEditFieldLabel_2.Visible = false;
                app.TrayectoriaEleccion.Visible = true;
                selectedButton = app.TrayectoriaEleccion.SelectedObject;
                if selectedButton == app.InterpolarBoton
                    app.caso = '1';
                elseif selectedButton == app.RectaBoton
                    app.caso = '2';
                else
                end
            elseif selectedButton == app.Articulares
                app.PosicinXEditFieldLabel.Text = "Motor A:";
                app.PosicinYEditFieldLabel.Text = "Motor B:";
                app.PosicinZEditFieldLabel.Text = "Motor C:";
                app.Stepper.Visible = true;
                app.StepperEditFieldLabel_2.Visible = true;
                app.TrayectoriaEleccion.Visible = false;
                app.caso = '3';
            else
            end
            
        end

        % Button pushed function: BotonHoming
        function Homing(app, event)
            BloquearBotones(app, false);
            write(app.mi_arduino, '1', "char"); %Habria que recibir de arduino 
            readline(app.mi_arduino);
            BloquearBotones(app, true);
        end

        % Button pushed function: BotonSetFree
        function Setfree(app, event)
            write(app.mi_arduino, '0', "char");
        end

        % Button pushed function: BotonEnviar
        function BotonEnviarPosiciones(app, event)
    BloquearBotones(app, false);
%Aqui diferenciamos entre trayectoria punto a punto, linea recta o articulares
%Recibiremos un valor que será 1-3


%necesitamos valores en cartesianas (final)

%valores en pulsos (inicio) "pi" esto lo recibimos del robot


%valores en cartesianas (inicio) "picart" con una funcion


     switch app.caso
         case '1'  %Interpolar
            
            pf(1) = app.Motor1_X.Value;
            pf(2) = app.Motor2_Y.Value;
            pf(3) = app.Motor3_Z.Value;
            write(app.mi_arduino, '3', "char");
            pi = ActualizarPulsos(app);
            pigrad = grad_pulse(pi);
            picart = [fpkDelta(pigrad(1),pigrad(2),pigrad(3)), pi(4)];
            tf = 1;
            N = 2;
            
            [~,xs]=moverRobot(picart,pf,tf); %pulse no se usa en esta funcion
            %devuelve matriz de  pulsos x * 3 y coordenada stepper(constante)
            
            strvalores(1,:) = [pi(1) pi(2) pi(3) xs];
            
            pfgrad=ipkDelta(pf(1), pf(2), pf(3));
            pfpuls(1)=pfgrad(1)*5.85;
            pfpuls(2)=pfgrad(2)*3.036;
            pfpuls(3)=pfgrad(3)*5.85;
    
    
            strvalores(2,:) = [pfpuls(1) pfpuls(2) pfpuls(3) xs];
             
         case '2'  %Recta
             
             
            pf(1) = app.Motor1_X.Value;
            pf(2) = app.Motor2_Y.Value;
            pf(3) = app.Motor3_Z.Value;
            write(app.mi_arduino, '3', "char");
            pi = ActualizarPulsos(app);
            pigrad = grad_pulse(pi);
            picart = [fpkDelta(pigrad(1),pigrad(2),pigrad(3)), pi(4)];
            tf = 0.2;
            [Pulse,xs]=moverRobot(picart,pf,tf);
            %devuelve matriz de  pulsos x * 3 y coordenada stepper(constante)
            
            strvalores(1,:) = [pi(1) pi(2) pi(3) xs];


            Pasos=length(Pulse);
                for j=1:Pasos 
                    sp=Pulse(j,:);
                    strvalores(j+1,:) = [sp(1) sp(2) sp(3) xs];
                end
            N = Pasos + 1; 


         case '3'  %Articulares
             
            qf(1) = app.Motor1_X.Value;
            qf(2) = app.Motor2_Y.Value;
            qf(3) = app.Motor3_Z.Value;
            qf(4) = app.Stepper.Value;
            write(app.mi_arduino, '3', "char");
            qi = ActualizarPulsos(app);
            N = 2;
            qffin(1)=qf(1)*5.85;
            qffin(2)=qf(2)*3.036;
            qffin(3)=qf(3)*5.85;
            strvalores(1,:) = [qi(1) qi(2) qi(3) qf(4)];
            strvalores(2,:) = [qffin(1) qffin(2) qffin(3) qf(4)];
         
      end
            
     
     error = false; 
            for i = 1:N
                for j = 1:4
                    if imag(strvalores(i,j)) ~= 0
                       error = true;
                    end
                end
            end
             
                    if app.modoChulo == true && error == false
                        num = '6'; %Enviamos modo chulo
                        
                        for i = 1:N   
                            write(app.mi_arduino, num, "char");
                            strvaloresnuevo = strjoin(string(strvalores(i,:)));
                            writeline(app.mi_arduino,strvaloresnuevo ); %Puede enviarlos de otras maneras
                            ActualizarPulsos(app); %Se actualizan los valores e impide overflow en arduino
                            %es decir, que o termine de moverse a la posicion1 y ya recoja la 2 y sin
                            %terminar 1 vaya a 2 que no se si pasa
                        end
                        
                    elseif app.modoChulo == false && error == false
                        num = '2'; %Enviamos modo normal
                        
                        for i = 1:N   
                            write(app.mi_arduino, num, "char");
                            strvaloresnuevo = strjoin(string(strvalores(i,:)));
                            writeline(app.mi_arduino,strvaloresnuevo );
                            ActualizarPulsos(app);
                    
                        end
                    else
                        VentanaError();
                    end


% Pide el error cometido al finalizar la trayectoria
            
%             write(app.mi_arduino, '4', "char");
% %             pause(1);
% %             read(app.mi_arduino, app.mi_arduino.NumBytesAvailable, "char")
%             
%             app.ErrorA.Value = str2double(readline(app.mi_arduino));
% %            error = app.ErrorA.Value
%             app.ErrorB.Value = str2double(readline(app.mi_arduino));
% %            error = app.ErrorB.Value
%             app.ErrorC.Value = str2double(readline(app.mi_arduino));
% %            error = app.ErrorC.Value
%             app.ErrorStp.Value = str2double(readline(app.mi_arduino));
% %            error = app.ErrorStp.Value
%              
%             end
            
BloquearBotones(app, true); 
        end

        % Selection changed function: TrayectoriaEleccion
        function CambiarEleccion(app, event)
            selectedButton = app.TrayectoriaEleccion.SelectedObject;
                if selectedButton == app.InterpolarBoton
                    app.caso = '1';
                elseif selectedButton == app.RectaBoton
                    app.caso = '2';
                else
                end
        end

        % Button pushed function: Depurar
        function Depuracion(app, event)
            if app.mi_arduino.NumBytesAvailable ~= 0
                read(app.mi_arduino,app.mi_arduino.NumBytesAvailable, "char")
            else
            end
        end

        % Image clicked function: Logo
        function CambiaModoChulo(app, event)
            app.modoChulo = true;
            app.LogoModoChulo.Visible = true;
            app.Logo.Visible = false;
        end

        % Image clicked function: LogoModoChulo
        function CambiaModoNoChulo(app, event)
            app.modoChulo = false;
            app.Logo.Visible = true;
            app.LogoModoChulo.Visible = false;
        end

        % Value changed function: GarraSwitch
        function GarraOnOff(app, event)
           write(app.mi_arduino, '5', "char");
        end

        % Button pushed function: CirCulo
        function Circulo(app, event)
            BloquearBotones(app, false);
            
            for i = 1:17
                x = app.RadioCirculo.Value * cos( 2*pi*(i-1) / 16);
                y = app.RadioCirculo.Value * sin( 2*pi*(i-1) / 16);
                z = app.EjeZCirculo.Value;
                stepper = 0;
                pfgrad=ipkDelta(x, y,z);
                pfpuls(1)=pfgrad(1)*5.85;
                pfpuls(2)=pfgrad(2)*3.036;
                pfpuls(3)=pfgrad(3)*5.85;                
                strvalores(i,:) = [pfpuls(1) , pfpuls(2), pfpuls(3), stepper];
                strvaloresnuevo = strjoin(string(strvalores(i,:)));
                write(app.mi_arduino, '2', "char");
                writeline(app.mi_arduino,strvaloresnuevo);
                ActualizarPulsos(app);
            end


            BloquearBotones(app, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.interfazprincipal = uifigure('Visible', 'off');
            app.interfazprincipal.Name = 'MATLAB App';
            app.LogoModoChulo = uiimage(app.interfazprincipal);
            app.LogoModoChulo.ImageClickedFcn = createCallbackFcn(app, @CambiaModoNoChulo, true);
            app.LogoModoChulo.Visible = 'off';
            app.LogoModoChulo.ImageSource = 'PULLUP_modochulo.png';
            app.RenderImagen = uiimage(app.interfazprincipal);
            app.RenderImagen.ImageSource = 'render motores.jpeg';
            app.PuertonoconectadoLabel = uilabel(app.interfazprincipal);
            app.PuertonoconectadoLabel.Text = 'Puerto no conectado';
            app.PuertoconectadoLuz = uilamp(app.interfazprincipal);
            app.ConexionArduinoBoton = uibutton(app.interfazprincipal, 'push');
            app.ConexionArduinoBoton.ButtonPushedFcn = createCallbackFcn(app, @AbrirVentanaConfigSerialPort, true);
            app.ConexionArduinoBoton.Text = 'Configurar';
            app.ConectarBoton = uibutton(app.interfazprincipal, 'push');
            app.ConectarBoton.ButtonPushedFcn = createCallbackFcn(app, @CrearSerialPort, true);
            app.ConectarBoton.Text = 'Conectar';
            app.BotonHoming = uibutton(app.interfazprincipal, 'push');
            app.BotonHoming.ButtonPushedFcn = createCallbackFcn(app, @Homing, true);
            app.BotonHoming.Enable = 'off';
            app.BotonHoming.Text = 'Homing';
            app.MotorAEditFieldLabel = uilabel(app.interfazprincipal);
            app.MotorAEditFieldLabel.Text = 'Motor A';
            app.MotorAValue = uieditfield(app.interfazprincipal, 'numeric');
            app.MotorAValue.Editable = 'off';
            app.MotorBEditFieldLabel = uilabel(app.interfazprincipal);
            app.MotorBEditFieldLabel.Text = 'Motor B';
            app.MotorBValue = uieditfield(app.interfazprincipal, 'numeric');
            app.MotorBValue.Editable = 'off';
            app.MotorCEditFieldLabel = uilabel(app.interfazprincipal);
            app.MotorCEditFieldLabel.Text = 'Motor C';
            app.MotorCValue = uieditfield(app.interfazprincipal, 'numeric');
            app.MotorCValue.Editable = 'off';
            app.PosicionGrupo = uibuttongroup(app.interfazprincipal);
            app.PosicionGrupo.SelectionChangedFcn = createCallbackFcn(app, @CambioSistCord, true);
            app.Cartesianas = uitogglebutton(app.PosicionGrupo);
            app.Cartesianas.Text = 'Cartesianas';
            app.Cartesianas.Value = true;
            app.Articulares = uitogglebutton(app.PosicionGrupo);
            app.Articulares.Text = 'Articulares';
            app.PosicinXEditFieldLabel = uilabel(app.PosicionGrupo);
            app.PosicinXEditFieldLabel.Text = 'Posición X:';
            app.Motor1_X = uieditfield(app.PosicionGrupo, 'numeric');
            app.PosicinYEditFieldLabel = uilabel(app.PosicionGrupo);
            app.PosicinYEditFieldLabel.Text = 'Posición Y:';
            app.Motor2_Y = uieditfield(app.PosicionGrupo, 'numeric');
            app.PosicinZEditFieldLabel = uilabel(app.PosicionGrupo);
            app.PosicinZEditFieldLabel.Text = 'Posición Z:';
            app.Motor3_Z = uieditfield(app.PosicionGrupo, 'numeric');
            app.BotonEnviar = uibutton(app.PosicionGrupo, 'push');
            app.BotonEnviar.ButtonPushedFcn = createCallbackFcn(app, @BotonEnviarPosiciones, true);
            app.BotonEnviar.Enable = 'off';
            app.BotonEnviar.Text = 'Enviar';
            app.StepperEditFieldLabel_2 = uilabel(app.PosicionGrupo);
            app.StepperEditFieldLabel_2.Visible = 'off';
            app.StepperEditFieldLabel_2.Text = 'Stepper:';
            app.Stepper = uieditfield(app.PosicionGrupo, 'numeric');
            app.Stepper.Visible = 'off';
            app.TrayectoriaEleccion = uibuttongroup(app.PosicionGrupo);
            app.TrayectoriaEleccion.SelectionChangedFcn = createCallbackFcn(app, @CambiarEleccion, true);
            app.TrayectoriaEleccion.Title = 'Trayectoria:';
            app.InterpolarBoton = uitogglebutton(app.TrayectoriaEleccion);
            app.InterpolarBoton.Text = 'Interpolar';
            app.InterpolarBoton.Value = true;
            app.RectaBoton = uitogglebutton(app.TrayectoriaEleccion);
            app.RectaBoton.Text = 'Recta';
            app.GarraSwitch = uiswitch(app.PosicionGrupo, 'rocker');
            app.GarraSwitch.Items = {'Cerrar Garra', 'Abrir Garra'};
            app.GarraSwitch.ValueChangedFcn = createCallbackFcn(app, @GarraOnOff, true);
            app.GarraSwitch.Value = 'Cerrar Garra';
            app.CirCulo = uibutton(app.PosicionGrupo, 'push');
            app.CirCulo.ButtonPushedFcn = createCallbackFcn(app, @Circulo, true);
            app.CirCulo.Enable = 'off';
            app.CirCulo.Text = 'CirCulo';
            app.EjeZEditFieldLabel = uilabel(app.PosicionGrupo);
            app.EjeZEditFieldLabel.Text = 'Eje Z:';
            app.EjeZCirculo = uieditfield(app.PosicionGrupo, 'numeric');
            app.EjeZCirculo.Value = -200;
            app.RadioEditFieldLabel = uilabel(app.PosicionGrupo);
            app.RadioEditFieldLabel.Text = 'Radio:';
            app.RadioCirculo = uieditfield(app.PosicionGrupo, 'numeric');
            app.RadioCirculo.Limits = [10 150];
            app.RadioCirculo.Value = 50;
            app.StepperEditFieldLabel = uilabel(app.interfazprincipal);
            app.StepperEditFieldLabel.Text = 'Stepper';
            app.StepperValue = uieditfield(app.interfazprincipal, 'numeric');
            app.StepperValue.Editable = 'off';
            app.BotonSetFree = uibutton(app.interfazprincipal, 'push');
            app.BotonSetFree.ButtonPushedFcn = createCallbackFcn(app, @Setfree, true);
            app.BotonSetFree.Enable = 'off';
            app.BotonSetFree.Text = 'Set Free';
            app.Depurar = uibutton(app.interfazprincipal, 'push');
            app.Depurar.ButtonPushedFcn = createCallbackFcn(app, @Depuracion, true);
            app.Depurar.Visible = 'off';
            app.Depurar.Text = 'Depurar';
            app.Logo = uiimage(app.interfazprincipal);
            app.Logo.ImageClickedFcn = createCallbackFcn(app, @CambiaModoChulo, true);
            app.Logo.ImageSource = 'PULLUP_normal.png';
            % Show the figure after all components are created
            app.interfazprincipal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.interfazprincipal)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.interfazprincipal)
        end
    end
end